classdef Autoalign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        P3BAutoAlignerUIFigure    matlab.ui.Figure
        ParallelizationMenu       matlab.ui.container.Menu
        StartparallelpoolMenu     matlab.ui.container.Menu
        CloseparallelpoolMenu     matlab.ui.container.Menu
        GridLayout                matlab.ui.container.GridLayout
        Lamp                      matlab.ui.control.Lamp
        ParallelizationLampLabel  matlab.ui.control.Label
        ImageviewerPanel          matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        flatSigma                 matlab.ui.control.NumericEditField
        FlatsigmaLabel            matlab.ui.control.Label
        view_imageSpinner         matlab.ui.control.Spinner
        ImageLabel                matlab.ui.control.Label
        view_imagePlaySeries      matlab.ui.control.Button
        view_imageType            matlab.ui.control.DropDown
        ViewLabel                 matlab.ui.control.Label
        ImportandalignP3BPanel    matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        export                    matlab.ui.control.Button
        asymmetryCalc             matlab.ui.control.Button
        autoalign                 matlab.ui.control.Button
        import                    matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Initialize structure p3b
        p3b = struct('imageRaw',[],'imageRawFlat',[],'imageAligned',[],'polarization',[],'energy',[],'I0',[]);
        pathname;
        coreCount;
        imgXA;
        imgXMCD;
        imgRawXA;
        imgRawXMCD;
        convention;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check if the parallel pool has already been initalized
            p = gcp('nocreate'); % If no pool, do not create new one.
            if isempty(p)
                app.Lamp.Color = [0.5,0.5,0.5];
            else
                app.Lamp.Color = [0,1,0];
            end
        end

        % Button pushed function: import
        function importButtonPushed(app, event)
            % Initialize structure p3b
            app.p3b = struct('imageRaw',[],'imageAligned',[],'polarization',[],'energy',[],'I0',[]);
            % Import P3B files
            [filename_m, app.pathname] = uigetfile(...
                '*.p3b','Select P3B files','MultiSelect','on'); 
            currentStatus = uiprogressdlg(app.P3BAutoAlignerUIFigure,'Message','Importing files.',...
                "Indeterminate","on");
            if iscell(filename_m)==0 
                I = readP3B([app.pathname filename_m]);    %reads the P3B headers and data
                app.p3b(1).imageRaw = reshape(I.DATA,[I.DIMX,I.DIMY])'; 
            else
                for t=1:length(filename_m)
                    filename=char(filename_m(t));
                    I = readP3B([app.pathname filename]);    %reads the P3B headers and data
                    app.p3b(t).energy = I.BEAMLINE_ENERGY;     % array for mono energy that each image is taken at
                    app.p3b(t).polarization=I.EPU_POLARIZATION;       % array for polarization that each image is taken at
                    app.p3b(t).I0=I.I0;                   % array for beamline Izero
                    app.p3b(t).imageRaw=reshape(I.DATA,[I.DIMX,I.DIMY]);   % 2D images stored in a 3D stack
                end
            end
            
            % Alter the slider limit to reflect the amount of p3b images imported
            app.view_imageSpinner.Limits = [1,length(filename_m)];
            app.view_imageSpinner.Value = 1;
            app.view_imageSpinner.Enable = 1;
            % Alter the dropdown menu to show the raw p3b images
            app.view_imageType.Value = "Raw PEEM images";
            app.view_imageType.Enable = 1;
            app.view_imagePlaySeries.Enable = 1;
            % Show the first image in the axes window
            p3bImShow(app,app.p3b(1).imageRaw);
            close(currentStatus)
        end

        % Value changed function: view_imageSpinner
        function view_imageSpinnerValueChanged(app, event)
            % Alters the image that is shown in the window; option only available for viewing image series
            switch app.view_imageType.Value
                case "Raw PEEM images"
                    p3bImShow(app,app.p3b(app.view_imageSpinner.Value).imageRaw);
                case "Aligned PEEM images"
                    p3bImShow(app,app.p3b(app.view_imageSpinner.Value).imageAligned);
            end
        end

        % Button pushed function: view_imagePlaySeries
        function view_imagePlaySeriesButtonPushed(app, event)
            % Plays image series
            [~,numel] = size(app.p3b);
            for i = 1:numel
                switch app.view_imageType.Value
                    case "Raw PEEM images"
                        p3bImShow(app,app.p3b(i).imageRaw);
                    case "Aligned PEEM images"
                        p3bImShow(app,app.p3b(i).imageAligned);
                end
                app.view_imageSpinner.Value = i;
                pause(0.05);
            end
        end

        % Button pushed function: autoalign
        function autoalignButtonPushed(app, event)
            % Perform image autoaligning using image registration
            % Set up the configuration to monomodal (similar brightness/intensity)
            [optimizer, metric] = imregconfig('monomodal');
            [~,numel] = size(app.p3b);
            
            % Make sure that, for the first image, we do not perform registration since all OTHER images
            % will be aligned to the first image
            app.p3b(1).imageAligned = app.p3b(1).imageRaw;
            imgMean = mean(app.p3b(1).imageRaw,'all');
            imgSTD = std(app.p3b(1).imageRaw,0,'all');
            p3bAlignTo = mat2gray(app.p3b(1).imageRaw, imgMean + 2*imgSTD*[-1,1]);
            % Save the images to be aligned into separate matrices for parfor calculation
            [width, height] = size(app.p3b(1).imageRaw);
            p3bImageSet = zeros(width,height,numel-1);
            % Save a separate variable to align to (solves overhead problem)
            for i = 1:numel-1
                p3bImageSet(:,:,i) = app.p3b(i+1).imageRaw;
            end
            % Make a separate matrix to store all the parfor aligned values
            p3bImageSetAligned = zeros(size(p3bImageSet));
            parDataQ = parallel.pool.DataQueue;
            currentStatus = uiprogressdlg(app.P3BAutoAlignerUIFigure,'Message','Images are being aligned');
            afterEach(parDataQ, @nUpdateWaitbar);
            p = 1;
            % Calculate alignments
            parfor i = 1:numel-1
                imgMean = mean(p3bImageSet(:,:,i),'all');
                imgSTD = std(p3bImageSet(:,:,i),0,'all');
                imgTemp = p3bImageSet(:,:,i);
                imgTempReg = mat2gray(imgTemp, imgMean + 2*imgSTD*[-1,1]);
                % Make sure that, for the first image, we do not perform registration since all OTHER images
                % will be aligned to the first image
                tform = imregtform(imgTempReg,p3bAlignTo,'translation',optimizer,metric);
                p3bImageSetAligned(:,:,i) = imwarp(imgTemp,tform,'OutputView',imref2d(size(p3bAlignTo)));
                send(parDataQ,i);
            end
            
            % Store values from the parfor into the structures
            for i = 1:numel-1
                % Set all zero regions to NaN
                tempImage = p3bImageSetAligned(:,:,i);
                tempImage(tempImage == 0) = NaN;
                app.p3b(i+1).imageAligned = tempImage;
            end
            
            % Display aligned images
            % Alter the slider limit to reflect the amount of p3b images imported
            app.view_imageSpinner.Value = 1;
            app.view_imageSpinner.Enable = 1;
            % Alter the dropdown menu to show the raw p3b images
            app.view_imageType.Value = "Aligned PEEM images";
            app.view_imageType.Enable = 1;
            app.view_imagePlaySeries.Enable = 1;
            % Show the first image in the axes window
            p3bImShow(app,app.p3b(1).imageAligned);
            close(currentStatus);
            % Function for the waitbar
            function nUpdateWaitbar(~)
                currentStatus.Value = p/(numel-1);
                p = p+1;
            end
        end

        % Callback function
        function multicoresButtonPushed(app, event)
            % Update the number of cores MATLAB can use for calculating the image registration
            app.coreCount = inputdlg('Enter the number of cores MATLAB can use to help speed up the alignment',...
                'Core counts');
            currentStatus = uiprogressdlg(app.P3BAutoAlignerUIFigure,'Message','MATLAB is initializing cores for parallel calculation.',...
                "Indeterminate","on");
            try
                parpool(str2num(app.coreCount{1})); %#ok<ST2NM> 
            catch ME
                uialert(app.P3BAutoAlignerUIFigure,...
                    sprintf('An error has occurred. If the error code mentions that it has found an interactive session, that means that the parpool is already activated. MATLAB has reported the following error:\n\n%s\n\nCheck function: "%s" at line %d',...
                    ME.message,ME.stack(1).name,ME.stack(1).line),'Error','Icon','error');
            end
             close(currentStatus);
        end

        % Menu selected function: CloseparallelpoolMenu
        function CloseparallelpoolMenuSelected(app, event)
            currentStatus = uiprogressdlg(app.P3BAutoAlignerUIFigure,'Message','MATLAB is closing the parallel pool.',...
                "Indeterminate","on");
            delete(gcp('nocreate'));
            app.Lamp.Color = [0,0,0];
            close(currentStatus)
        end

        % Value changed function: view_imageType
        function view_imageTypeValueChanged(app, event)
            % Enables/disables options depending on what option has been selected
            switch app.view_imageType.Value
                case "Raw PEEM images"
                    app.view_imageSpinner.Enable = 1;
                    app.view_imageSpinner.Value = 1;
                    app.view_imagePlaySeries.Enable = 1;
                    p3bImShow(app,app.p3b(1).imageRaw);
                case "Aligned PEEM images"
                    app.view_imageSpinner.Enable = 1;
                    app.view_imageSpinner.Value = 1;
                    app.view_imagePlaySeries.Enable = 1;
                    p3bImShow(app,app.p3b(1).imageAligned);
                case "Raw XA-PEEM"
                    app.view_imageSpinner.Enable = 0;
                    app.view_imagePlaySeries.Enable = 0;
                    p3bImShow(app,app.imgRawXA);
                case "Raw asymmetry"
                    app.view_imageSpinner.Enable = 0;
                    app.view_imagePlaySeries.Enable = 0;
                    p3bImShow(app,app.imgRawXMCD);
                case "Flatfield XA-PEEM"
                    app.view_imageSpinner.Enable = 0;
                    app.view_imagePlaySeries.Enable = 0;
                    p3bImShow(app,app.imgXA);
                case "Flatfield asymmetry"
                    app.view_imageSpinner.Enable = 0;
                    app.view_imagePlaySeries.Enable = 0;
                    p3bImShow(app,app.imgXMCD);
            end
        end

        % Button pushed function: asymmetryCalc
        function asymmetryCalcButtonPushed(app, event)
            % Ask the user whether they want to use RCP-LCP or LCP-RCP convention
            app.convention = uiconfirm(app.P3BAutoAlignerUIFigure,'Select the method you wish to perform asymmetry calculation with.',...
                'Asymmetry option','Options',{'(RCP-LCP)/Sum','(LCP-RCP)/Sum'});
            asymmetryRawCalc(app);
            asymmetryCalc(app,app.flatSigma.Value);
        end

        % Value changed function: flatSigma
        function flatSigmaValueChanged(app, event)
            %Updates XMCD calculation
            asymmetryCalc(app,app.flatSigma.Value);
        end

        % Button pushed function: export
        function exportButtonPushed(app, event)
            exportPEEM(app);
        end

        % Menu selected function: StartparallelpoolMenu
        function StartparallelpoolMenuSelected(app, event)
            % Update the number of cores MATLAB can use for calculating the image registration
            app.coreCount = inputdlg('Enter the number of cores MATLAB can use to help speed up the alignment',...
                'Core counts');
            currentStatus = uiprogressdlg(app.P3BAutoAlignerUIFigure,'Message','MATLAB is initializing cores for parallel calculation.',...
                "Indeterminate","on");
            try
                parpool(str2num(app.coreCount{1})); %#ok<ST2NM> 
                app.Lamp.Color = [0,1,0];
            catch ME
                uialert(app.P3BAutoAlignerUIFigure,...
                    sprintf('An error has occurred. If the error code mentions that it has found an interactive session, that means that the parpool is already activated. MATLAB has reported the following error:\n\n%s\n\nCheck function: "%s" at line %d',...
                    ME.message,ME.stack(1).name,ME.stack(1).line),'Error','Icon','error');
            end
             close(currentStatus);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.P3BAutoAlignerUIFigure = uifigure('Visible', 'off');
            app.P3BAutoAlignerUIFigure.Name = 'P3B Auto-Aligner';
            app.ParallelizationMenu = uimenu(app.P3BAutoAlignerUIFigure);
            app.ParallelizationMenu.Text = 'Parallelization';
            app.StartparallelpoolMenu = uimenu(app.ParallelizationMenu);
            app.StartparallelpoolMenu.MenuSelectedFcn = createCallbackFcn(app, @StartparallelpoolMenuSelected, true);
            app.StartparallelpoolMenu.Text = 'Start parallel pool';
            app.CloseparallelpoolMenu = uimenu(app.ParallelizationMenu);
            app.CloseparallelpoolMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseparallelpoolMenuSelected, true);
            app.CloseparallelpoolMenu.Text = 'Close parallel pool';
            app.GridLayout = uigridlayout(app.P3BAutoAlignerUIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {};
            app.UIAxes.ZTick = [];
            app.UIAxes.ZTickLabel = '';
            app.ImportandalignP3BPanel = uipanel(app.GridLayout);
            app.ImportandalignP3BPanel.Title = 'Import and align P3B';
            app.GridLayout2 = uigridlayout(app.ImportandalignP3BPanel);
            app.import = uibutton(app.GridLayout2, 'push');
            app.import.ButtonPushedFcn = createCallbackFcn(app, @importButtonPushed, true);
            app.import.Text = 'Import P3B files';
            app.autoalign = uibutton(app.GridLayout2, 'push');
            app.autoalign.ButtonPushedFcn = createCallbackFcn(app, @autoalignButtonPushed, true);
            app.autoalign.Text = 'Autoalign images';
            app.asymmetryCalc = uibutton(app.GridLayout2, 'push');
            app.asymmetryCalc.ButtonPushedFcn = createCallbackFcn(app, @asymmetryCalcButtonPushed, true);
            app.asymmetryCalc.Text = 'Calc asymm + XA';
            app.export = uibutton(app.GridLayout2, 'push');
            app.export.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.export.Text = 'Export';
            app.ImageviewerPanel = uipanel(app.GridLayout);
            app.ImageviewerPanel.Title = 'Image viewer';
            app.GridLayout3 = uigridlayout(app.ImageviewerPanel);
            app.ViewLabel = uilabel(app.GridLayout3);
            app.ViewLabel.Text = 'View:';
            app.view_imageType = uidropdown(app.GridLayout3);
            app.view_imageType.Items = {'Raw PEEM images', 'Aligned PEEM images', 'Raw XA-PEEM', 'Raw asymmetry', 'Flatfield XA-PEEM', 'Flatfield asymmetry'};
            app.view_imageType.ValueChangedFcn = createCallbackFcn(app, @view_imageTypeValueChanged, true);
            app.view_imageType.Enable = 'off';
            app.view_imageType.Value = 'Raw PEEM images';
            app.view_imagePlaySeries = uibutton(app.GridLayout3, 'push');
            app.view_imagePlaySeries.ButtonPushedFcn = createCallbackFcn(app, @view_imagePlaySeriesButtonPushed, true);
            app.view_imagePlaySeries.Enable = 'off';
            app.view_imagePlaySeries.Text = 'Play image series';
            app.ImageLabel = uilabel(app.GridLayout3);
            app.ImageLabel.Text = 'Image #:';
            app.view_imageSpinner = uispinner(app.GridLayout3);
            app.view_imageSpinner.Limits = [1 Inf];
            app.view_imageSpinner.RoundFractionalValues = 'on';
            app.view_imageSpinner.ValueChangedFcn = createCallbackFcn(app, @view_imageSpinnerValueChanged, true);
            app.view_imageSpinner.Enable = 'off';
            app.view_imageSpinner.Value = 1;
            app.FlatsigmaLabel = uilabel(app.GridLayout3);
            app.FlatsigmaLabel.Enable = 'off';
            app.FlatsigmaLabel.Text = 'Flat sigma';
            app.flatSigma = uieditfield(app.GridLayout3, 'numeric');
            app.flatSigma.ValueChangedFcn = createCallbackFcn(app, @flatSigmaValueChanged, true);
            app.flatSigma.Enable = 'off';
            app.flatSigma.Value = 100;
            app.ParallelizationLampLabel = uilabel(app.GridLayout);
            app.ParallelizationLampLabel.Text = 'Parallelization';
            app.Lamp = uilamp(app.GridLayout);
            % Show the figure after all components are created
            app.P3BAutoAlignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Autoalign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.P3BAutoAlignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.P3BAutoAlignerUIFigure)
        end
    end
end